classdef EqualizerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BrowseButton        matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        Slide_250           matlab.ui.control.Slider
        Label_4             matlab.ui.control.Label
        Slide_500           matlab.ui.control.Slider
        Label_5             matlab.ui.control.Label
        Slide_1000          matlab.ui.control.Slider
        Label_6             matlab.ui.control.Label
        Slide_2000          matlab.ui.control.Slider
        Label_7             matlab.ui.control.Label
        Slide_3000          matlab.ui.control.Slider
        Label_8             matlab.ui.control.Label
        Slide_4000          matlab.ui.control.Slider
        Label_9             matlab.ui.control.Label
        Slide_6000          matlab.ui.control.Slider
        fileEditFieldLabel  matlab.ui.control.Label
        fileEditField       matlab.ui.control.EditField
        SetZeroGainsButton  matlab.ui.control.Button
        Label_10            matlab.ui.control.Label
        Slide_8000          matlab.ui.control.Slider
        Label_11            matlab.ui.control.Label
        Slide_12000         matlab.ui.control.Slider
        Label_12            matlab.ui.control.Label
        Slide_20000         matlab.ui.control.Slider
        FrequencyLabel      matlab.ui.control.Label
        Label_13            matlab.ui.control.Label
        Slider_12           matlab.ui.control.Slider
        UIAxes              matlab.ui.control.UIAxes
        PlayButton          matlab.ui.control.Button
        PauseButton         matlab.ui.control.Button
        StopButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       f125 = app.Slider_12.Value ;
            f250 = app.Slide_250.Value ;
            f500 = app.Slide_500.Value ;
            f1000 = app.Slide_1000.Value ;
            f2000 = app.Slide_2000.Value ;
            f3000 = app.Slide_3000.Value ;
            f4000 = app.Slide_4000.Value ;
            f6000 = app.Slide_6000.Value ;
            f8000 = app.Slide_8000.Value ;
            f12000 = app.Slide_12000.Value ;
            f20000 = app.Slide_20000.Value ;
            
            fplot(app.UIAxes,0,[0 20000]) ;


        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('C:\Users\addep\OneDrive\Dokument\Equalizer-TFYY51\Ljudfiler') ;
            app.fileEditField.Value = file ;
        end

        % Value changed function: fileEditField
        function fileEditFieldValueChanged(app, event)
         
            
        end

        % Button pushed function: SetZeroGainsButton
        function SetZeroGainsButtonPushed(app, event)
           app.Slide_250.Value = 0 ;
           app.Slide_500.Value = 0 ;
           app.Slide_1000.Value = 0 ;
           app.Slide_2000.Value = 0 ;
           app.Slide_3000.Value = 0 ;
           app.Slide_4000.Value = 0 ;
           app.Slide_6000.Value = 0 ;
           app.Slide_8000.Value = 0 ;
           app.Slide_12000.Value = 0 ;
           app.Slide_20000.Value = 0 ;
           app.Slider_12.Value = 0 ;
           
        end

        % Value changed function: Slider_12
        function Slider_12ValueChanged(app, event)
           f125 = app.Slider_12.Value ;
            fplot(app.UIAxes,(f125-0),[0 125]) ;
         
        end

        % Value changed function: Slide_250
        function Slide_250ValueChanged(app, event)
           
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '250';
            app.Slide_250 = uislider(app.UIFigure);
            app.Slide_250.Limits = [-70 70];
            app.Slide_250.MajorTicks = [-70 0 70];
            app.Slide_250.ValueChangedFcn = createCallbackFcn(app, @Slide_250ValueChanged, true);
            app.Slide_250.MinorTicks = [];
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '500';
            app.Slide_500 = uislider(app.UIFigure);
            app.Slide_500.Limits = [-70 70];
            app.Slide_500.MajorTicks = [-70 0 70];
            app.Slide_500.MinorTicks = [];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '1000';
            app.Slide_1000 = uislider(app.UIFigure);
            app.Slide_1000.Limits = [-70 70];
            app.Slide_1000.MajorTicks = [-70 0 70];
            app.Slide_1000.MinorTicks = [];
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '2000';
            app.Slide_2000 = uislider(app.UIFigure);
            app.Slide_2000.Limits = [-70 70];
            app.Slide_2000.MajorTicks = [-70 0 70];
            app.Slide_2000.MinorTicks = [];
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '3000';
            app.Slide_3000 = uislider(app.UIFigure);
            app.Slide_3000.Limits = [-70 70];
            app.Slide_3000.MajorTicks = [-70 0 70];
            app.Slide_3000.MinorTicks = [];
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '4000';
            app.Slide_4000 = uislider(app.UIFigure);
            app.Slide_4000.Limits = [-70 70];
            app.Slide_4000.MajorTicks = [-70 0 70];
            app.Slide_4000.MinorTicks = [];
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '6000';
            app.Slide_6000 = uislider(app.UIFigure);
            app.Slide_6000.Limits = [-70 70];
            app.Slide_6000.MajorTicks = [-70 0 70];
            app.Slide_6000.MinorTicks = [];
            app.fileEditFieldLabel = uilabel(app.UIFigure);
            app.fileEditFieldLabel.Text = 'file:';
            app.fileEditField = uieditfield(app.UIFigure, 'text');
            app.fileEditField.ValueChangedFcn = createCallbackFcn(app, @fileEditFieldValueChanged, true);
            app.SetZeroGainsButton = uibutton(app.UIFigure, 'push');
            app.SetZeroGainsButton.ButtonPushedFcn = createCallbackFcn(app, @SetZeroGainsButtonPushed, true);
            app.SetZeroGainsButton.Text = 'Set Zero Gains';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '8000';
            app.Slide_8000 = uislider(app.UIFigure);
            app.Slide_8000.Limits = [-70 70];
            app.Slide_8000.MajorTicks = [-70 0 70];
            app.Slide_8000.MinorTicks = [];
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '12000';
            app.Slide_12000 = uislider(app.UIFigure);
            app.Slide_12000.Limits = [-70 70];
            app.Slide_12000.MajorTicks = [-70 0 70];
            app.Slide_12000.MinorTicks = [];
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '20000';
            app.Slide_20000 = uislider(app.UIFigure);
            app.Slide_20000.Limits = [-70 70];
            app.Slide_20000.MajorTicks = [-70 0 70];
            app.Slide_20000.MinorTicks = [];
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency:';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '125';
            app.Slider_12 = uislider(app.UIFigure);
            app.Slider_12.Limits = [-70 70];
            app.Slider_12.MajorTicks = [-70 0 70];
            app.Slider_12.ValueChangedFcn = createCallbackFcn(app, @Slider_12ValueChanged, true);
            app.Slider_12.MinorTicks = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Frequency (kHz)')
            ylabel(app.UIAxes, 'Gain (dB)')
            app.UIAxes.XLim = [0 20000];
            app.UIAxes.YLim = [-70 70];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [0 125 250 500 1000 2000 3000 4000 6000 8000 12000 20000];
            app.UIAxes.XTickLabel = {'0'; '125'; '250'; '500'; '1000'; '2000'; '3000'; '4000'; '6000'; '8000'; '12000'; '20000'};
            app.UIAxes.YTick = [-70 -30 0 30 70];
            app.UIAxes.YTickLabel = {'-70'; '-30'; '0'; '30'; '70'};
            app.UIAxes.XScale = 'log';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EqualizerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end